classdef SAGA_Data_Visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAGADataViewerUIFigure          matlab.ui.Figure
        DateTimeLabel                   matlab.ui.control.Label
        SAGALabel                       matlab.ui.control.Label
        ParametersConnectionStatusLamp  matlab.ui.control.Lamp
        ParametersConnectionStatusLampLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        UnipolarStreamTab               matlab.ui.container.Tab
        UnipolarStreamGridLayout        matlab.ui.container.GridLayout
        UnipolarStreamSettingsPanel     matlab.ui.container.Panel
        UnipolarCARCheckBox             matlab.ui.control.CheckBox
        UnipolarCutoff2EditField        matlab.ui.control.NumericEditField
        Cutoff2HzEditFieldLabel         matlab.ui.control.Label
        UnipolarCutoff1EditField        matlab.ui.control.NumericEditField
        Cutoff1HzEditFieldLabel         matlab.ui.control.Label
        UnipolarFilterCheckBox          matlab.ui.control.CheckBox
        UnipolarPerChannelOffsetEditField  matlab.ui.control.NumericEditField
        PerChannelOffsetVEditFieldLabel  matlab.ui.control.Label
        ToggleAllUnipolarChannelsSwitch  matlab.ui.control.ToggleSwitch
        AllChannelsSwitchLabel          matlab.ui.control.Label
        UnipolarStreamChannelsPanel     matlab.ui.container.Panel
        UNI64SwitchLabel                matlab.ui.control.Label
        UNI64Switch                     matlab.ui.control.Switch
        UNI63SwitchLabel                matlab.ui.control.Label
        UNI63Switch                     matlab.ui.control.Switch
        UNI62SwitchLabel                matlab.ui.control.Label
        UNI62Switch                     matlab.ui.control.Switch
        UNI61SwitchLabel                matlab.ui.control.Label
        UNI61Switch                     matlab.ui.control.Switch
        UNI60SwitchLabel                matlab.ui.control.Label
        UNI60Switch                     matlab.ui.control.Switch
        UNI59SwitchLabel                matlab.ui.control.Label
        UNI59Switch                     matlab.ui.control.Switch
        UNI58SwitchLabel                matlab.ui.control.Label
        UNI58Switch                     matlab.ui.control.Switch
        UNI57SwitchLabel                matlab.ui.control.Label
        UNI57Switch                     matlab.ui.control.Switch
        UNI56SwitchLabel                matlab.ui.control.Label
        UNI56Switch                     matlab.ui.control.Switch
        UNI55SwitchLabel                matlab.ui.control.Label
        UNI55Switch                     matlab.ui.control.Switch
        UNI54SwitchLabel                matlab.ui.control.Label
        UNI54Switch                     matlab.ui.control.Switch
        UNI53SwitchLabel                matlab.ui.control.Label
        UNI53Switch                     matlab.ui.control.Switch
        UNI52SwitchLabel                matlab.ui.control.Label
        UNI52Switch                     matlab.ui.control.Switch
        UNI51SwitchLabel                matlab.ui.control.Label
        UNI51Switch                     matlab.ui.control.Switch
        UNI50SwitchLabel                matlab.ui.control.Label
        UNI50Switch                     matlab.ui.control.Switch
        UNI49SwitchLabel                matlab.ui.control.Label
        UNI49Switch                     matlab.ui.control.Switch
        UNI48SwitchLabel                matlab.ui.control.Label
        UNI48Switch                     matlab.ui.control.Switch
        UNI47SwitchLabel                matlab.ui.control.Label
        UNI47Switch                     matlab.ui.control.Switch
        UNI46SwitchLabel                matlab.ui.control.Label
        UNI46Switch                     matlab.ui.control.Switch
        UNI45SwitchLabel                matlab.ui.control.Label
        UNI45Switch                     matlab.ui.control.Switch
        UNI44SwitchLabel                matlab.ui.control.Label
        UNI44Switch                     matlab.ui.control.Switch
        UNI43SwitchLabel                matlab.ui.control.Label
        UNI43Switch                     matlab.ui.control.Switch
        UNI42SwitchLabel                matlab.ui.control.Label
        UNI42Switch                     matlab.ui.control.Switch
        UNI41SwitchLabel                matlab.ui.control.Label
        UNI41Switch                     matlab.ui.control.Switch
        UNI40SwitchLabel                matlab.ui.control.Label
        UNI40Switch                     matlab.ui.control.Switch
        UNI39SwitchLabel                matlab.ui.control.Label
        UNI39Switch                     matlab.ui.control.Switch
        UNI38SwitchLabel                matlab.ui.control.Label
        UNI38Switch                     matlab.ui.control.Switch
        UNI37SwitchLabel                matlab.ui.control.Label
        UNI37Switch                     matlab.ui.control.Switch
        UNI36SwitchLabel                matlab.ui.control.Label
        UNI36Switch                     matlab.ui.control.Switch
        UNI35SwitchLabel                matlab.ui.control.Label
        UNI35Switch                     matlab.ui.control.Switch
        UNI34SwitchLabel                matlab.ui.control.Label
        UNI34Switch                     matlab.ui.control.Switch
        UNI33SwitchLabel                matlab.ui.control.Label
        UNI33Switch                     matlab.ui.control.Switch
        UNI32SwitchLabel                matlab.ui.control.Label
        UNI32Switch                     matlab.ui.control.Switch
        UNI31SwitchLabel                matlab.ui.control.Label
        UNI31Switch                     matlab.ui.control.Switch
        UNI30SwitchLabel                matlab.ui.control.Label
        UNI30Switch                     matlab.ui.control.Switch
        UNI29SwitchLabel                matlab.ui.control.Label
        UNI29Switch                     matlab.ui.control.Switch
        UNI28SwitchLabel                matlab.ui.control.Label
        UNI28Switch                     matlab.ui.control.Switch
        UNI27SwitchLabel                matlab.ui.control.Label
        UNI27Switch                     matlab.ui.control.Switch
        UNI26SwitchLabel                matlab.ui.control.Label
        UNI26Switch                     matlab.ui.control.Switch
        UNI25SwitchLabel                matlab.ui.control.Label
        UNI25Switch                     matlab.ui.control.Switch
        UNI24SwitchLabel                matlab.ui.control.Label
        UNI24Switch                     matlab.ui.control.Switch
        UNI23SwitchLabel                matlab.ui.control.Label
        UNI23Switch                     matlab.ui.control.Switch
        UNI22SwitchLabel                matlab.ui.control.Label
        UNI22Switch                     matlab.ui.control.Switch
        UNI21SwitchLabel                matlab.ui.control.Label
        UNI21Switch                     matlab.ui.control.Switch
        UNI20SwitchLabel                matlab.ui.control.Label
        UNI20Switch                     matlab.ui.control.Switch
        UNI19SwitchLabel                matlab.ui.control.Label
        UNI19Switch                     matlab.ui.control.Switch
        UNI18SwitchLabel                matlab.ui.control.Label
        UNI18Switch                     matlab.ui.control.Switch
        UNI17SwitchLabel                matlab.ui.control.Label
        UNI17Switch                     matlab.ui.control.Switch
        UNI16SwitchLabel                matlab.ui.control.Label
        UNI16Switch                     matlab.ui.control.Switch
        UNI15SwitchLabel                matlab.ui.control.Label
        UNI15Switch                     matlab.ui.control.Switch
        UNI14SwitchLabel                matlab.ui.control.Label
        UNI14Switch                     matlab.ui.control.Switch
        UNI13SwitchLabel                matlab.ui.control.Label
        UNI13Switch                     matlab.ui.control.Switch
        UNI12SwitchLabel                matlab.ui.control.Label
        UNI12Switch                     matlab.ui.control.Switch
        UNI11SwitchLabel                matlab.ui.control.Label
        UNI11Switch                     matlab.ui.control.Switch
        UNI10SwitchLabel                matlab.ui.control.Label
        UNI10Switch                     matlab.ui.control.Switch
        UNI09SwitchLabel                matlab.ui.control.Label
        UNI09Switch                     matlab.ui.control.Switch
        UNI08SwitchLabel                matlab.ui.control.Label
        UNI08Switch                     matlab.ui.control.Switch
        UNI07SwitchLabel                matlab.ui.control.Label
        UNI07Switch                     matlab.ui.control.Switch
        UNI06SwitchLabel                matlab.ui.control.Label
        UNI06Switch                     matlab.ui.control.Switch
        UNI05SwitchLabel                matlab.ui.control.Label
        UNI05Switch                     matlab.ui.control.Switch
        UNI04SwitchLabel                matlab.ui.control.Label
        UNI04Switch                     matlab.ui.control.Switch
        UNI03SwitchLabel                matlab.ui.control.Label
        UNI03Switch                     matlab.ui.control.Switch
        UNI02SwitchLabel                matlab.ui.control.Label
        UNI02Switch                     matlab.ui.control.Switch
        UNI01Switch                     matlab.ui.control.Switch
        UNI01SwitchLabel                matlab.ui.control.Label
        UnipolarUIAxes                  matlab.ui.control.UIAxes
        BipolarStreamTab                matlab.ui.container.Tab
        BipolarStreamGridLayout         matlab.ui.container.GridLayout
        BipolarStreamSettingsPanel      matlab.ui.container.Panel
        BipolarCutoff2EditField         matlab.ui.control.NumericEditField
        BipolarCutoff2EditFieldLabel    matlab.ui.control.Label
        BipolarCutoff1EditField         matlab.ui.control.NumericEditField
        BipolarCutoff1EditFieldLabel    matlab.ui.control.Label
        BipolarPerChannelOffsetEditField  matlab.ui.control.NumericEditField
        BipolarPerChannelOffsetVEditFieldLabel  matlab.ui.control.Label
        BipolarFilterCheckBox           matlab.ui.control.CheckBox
        ToggleAllBipolarChannelsSwitch  matlab.ui.control.ToggleSwitch
        ToggleAllBipolarChannelsSwitchLabel  matlab.ui.control.Label
        BipolarStreamChannelsPanel      matlab.ui.container.Panel
        BIP04SwitchLabel                matlab.ui.control.Label
        BIP04Switch                     matlab.ui.control.Switch
        BIP03SwitchLabel                matlab.ui.control.Label
        BIP03Switch                     matlab.ui.control.Switch
        BIP02SwitchLabel                matlab.ui.control.Label
        BIP02Switch                     matlab.ui.control.Switch
        BIP01Switch                     matlab.ui.control.Switch
        BIP01SwitchLabel                matlab.ui.control.Label
        BipolarUIAxes                   matlab.ui.control.UIAxes
        UnipolarAverageTab              matlab.ui.container.Tab
        UnipolarAverageGridLayout       matlab.ui.container.GridLayout
        UnipolarAverageSettingsPanel    matlab.ui.container.Panel
        BipolarAverageTab               matlab.ui.container.Tab
        BipolarAverageGridLayout        matlab.ui.container.GridLayout
        BipolarAverageSettingsPanel     matlab.ui.container.Panel
        UnipolarRasterTab               matlab.ui.container.Tab
        UnipolarRasterGridLayout        matlab.ui.container.GridLayout
        UnipolarRasterSettingsPanel     matlab.ui.container.Panel
        ICARasterTab                    matlab.ui.container.Tab
        ICARasterGridLayout             matlab.ui.container.GridLayout
        ICARasterSettingsPanel          matlab.ui.container.Panel
        RMSContourTab                   matlab.ui.container.Tab
        RMSContourGridLayout            matlab.ui.container.GridLayout
        RMSContourSettingsPanel         matlab.ui.container.Panel
    end

    
    properties (Access = public)
        data
    end
    
    properties (Hidden, Access = public)
        CDATA
    end
    
    properties (GetAccess = public, SetAccess = protected)
        Tag         % Either "A" or "B"
        cfg         % Parsed configuration struct
        connected = false; % True if the parameters server (from controller UI) is connected
        mode        % Current "mode" based on selected visualizer tab
        plot_fcn    % Callback to use in handling data
        filters     % Filter coefficients and parameters
        H           % Struct containing the different graphic objects to update
        tcp         % TCP server
        udp         % UDP Port that broadcasts the 'Tag' property any time the Tab is changed.
    end
    
    methods (Access = public)
        function update_bipolar_stream_data(app, ch, data)
            %UPDATE_BIPOLAR_STREAM_DATA  Update bipolar stream handles
            app.H.stream.BIP(ch).YData = data;
            drawnow;
        end
        
        function update_unipolar_stream_data(app, ch, data)
            %UPDATE_UNIPOLAR_STREAM_DATA  Update bipolar stream handles
            app.H.stream.UNI(ch).YData = data;
            drawnow;
        end
        
        function setConnectionStatus(app, src, evt)
            %SETCONNECTIONSTATUS  Indicate whether the app has a client (the controller application) connected over TCP.
            if evt.Connected
                app.ParametersConnectionStatusLamp.Color = [0.39,0.83,0.07];
                app.connected = true;
            else
                app.ParametersConnectionStatusLamp.Color = [0.65,0.65,0.65];
                app.connected = false;
            end
            app.setDateTimeLabel(evt.AbsoluteTime);
        end
        
        function setDateTimeLabel(app, dt)
            %SETDATETIMELABEL Set the text of the datetime label at the top of the interface.
            %
            % Inputs:
            %   dt - Datetime (scalar)
            app.DateTimeLabel.Text = sprintf("%4d-%02d-%02d %02d:%02d:%05.3f", ...
                year(dt), month(dt), day(dt), hour(dt), minute(dt), second(dt));
        end
    end
    
    methods (Access = private)
        function disable_bipolar_stream(app, chnum)
            %DISABLE_BIPOLAR_STREAM  Disable the selected channel(s)
            %
            % Syntax:
            %   app.disable_bipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to disable.
            app.cfg.Channels.en.BIP(chnum) = false(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.BipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.stream.BIP(chnum(ii)).Visible = 'off';
                h.Value = 'Off';
            end
        end
        
        function enable_bipolar_stream(app, chnum)
            %ENABLE_BIPOLAR_STREAM  Enable the selected channel(s)
            %
            % Syntax:
            %   app.enable_bipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to enable.
            app.cfg.Channels.en.BIP(chnum) = true(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.BipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.stream.BIP(chnum(ii)).Visible = 'on';
                h.Value = 'On';
            end
        end
        function disable_unipolar_stream(app, chnum)
            %DISABLE_UNIPOLAR_STREAM  Disable the selected channel(s)
            %
            % Syntax:
            %   app.disable_unipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to disable.
            app.cfg.Channels.en.UNI(chnum) = false(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.UnipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.stream.UNI(chnum(ii)).Visible = 'off';
                h.Value = 'Off';
            end
        end
        
        function enable_unipolar_stream(app, chnum)
            %ENABLE_UNIPOLAR_STREAM  Enable the selected channel(s)
            %
            % Syntax:
            %   app.enable_unipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to enable.
            app.cfg.Channels.en.UNI(chnum) = true(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.UnipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.stream.UNI(chnum(ii)).Visible = 'on';
                h.Value = 'On';
            end
        end
        
        function c = init_cdata(app)
            %INIT_CDATA Helper function to initialize the colormap stuff
            my_colormap = cm.map('rosette');
            cmobj = cm.cmap([1 size(my_colormap,1)], my_colormap);
            c = struct( ...
                'UNI', double(cmobj(linspace(1,32,64)))./255.0, ...
                'BIP', double(cmobj(linspace(1,32,4)))./255.0 ...
                );
            app.CDATA = c;
        end
        
        function init_filters(app)
            %INIT_FILTERS Helper function to initialize filter coefficients
            % Setup filters struct
            app.filters = struct(...
                'UNI', struct('b', [], 'a', [], 'en', app.UnipolarFilterCheckBox.Value == 'on', 'CAR', app.UnipolarCARCheckBox.Value == 'on'), ...
                'BIP', struct('b', [], 'a', [], 'en', app.BipolarFilterCheckBox.Value == 'on'));
            [app.filters.UNI.b,app.filters.UNI.a] =  ...
                butter(2,([app.UnipolarCutoff1EditField.Value, app.UnipolarCutoff2EditField.Value])./(app.cfg.Default.Sample_Rate/2), 'bandpass');
            [app.filters.BIP.b,app.filters.BIP.a] =  ...
                butter(2,([app.BipolarCutoff1EditField.Value, app.BipolarCutoff2EditField.Value])./(app.cfg.Default.Sample_Rate/2), 'bandpass');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tag, config, varargin)
            app.Tag = tag;
            set(app.SAGADataViewerUIFigure, ...
                'Name', sprintf("SAGA-%s Data Viewer", app.Tag), ...
                'Tag', sprintf('fig.DataViewer.%s', app.Tag));
            if nargin < 3
                config = parse_main_config();
            end
            app.cfg = struct('SAGA', config.SAGA.(tag), ...
                             'Default', config.Default, ...
                             'Server', config.Server);    
            app.H = struct; 
            f = setdiff(string(fieldnames(app.cfg.SAGA.Channels)), "n");
            for iF = 1:numel(f)
                app.cfg.SAGA.Channels.en.(f(iF)) = false(size(app.cfg.SAGA.Channels.(f(iF))));
            end
            
            app.udp = udpport("byte", "LocalPort", config.Server.UDP.extra, "EnablePortSharing", true);
            app.tcp = tcpserver(config.Server.Address.TCP, config.Server.TCP.(tag).Parameters, ...
                'ConnectionChangedFcn', @(src,evt)app.setConnectionStatus(src,evt));
            
            % Initialize stream colors and x-axis sample times vector.
            cdata = app.init_cdata();
            xvec = (0:(app.cfg.SAGA.Channels.n.samples-1))./app.cfg.Default.Sample_Rate;
            
            % Create struct to store the unipolar stream lines in.
            app.H.stream = struct;
            app.H.stream.UNI = gobjects(numel(app.cfg.SAGA.Channels.UNI), 1);
            
            for ii = 1:numel(app.H.stream.UNI)
                offset = app.UnipolarPerChannelOffsetEditField.Value * (ii-1);
                app.H.stream.UNI(ii) = line(app.UnipolarUIAxes, xvec, zeros(size(xvec)) + offset, 'Color', cdata.UNI(ii,:), 'LineWidth', 1.5, 'DisplayName', sprintf('UNI%02d', ii), 'Visible', 'off' );
                app.(sprintf('UNI%02dSwitch',ii)).FontColor = cdata.UNI(ii,:);
                app.(sprintf('UNI%02dSwitchLabel', ii)).FontColor = cdata.UNI(ii,:);                
            end
            
            % Create struct to store the bipolar stream lines in.
            app.H.stream.BIP = gobjects(numel(app.cfg.SAGA.Channels.BIP), 1);
            
            for ii = 1:numel(app.H.stream.BIP)
                offset = app.BipolarPerChannelOffsetEditField.Value * (ii-1);
                app.H.stream.BIP(ii) = line(app.BipolarUIAxes, xvec, zeros(size(xvec)) + offset, 'Color', cdata.BIP(ii,:), 'LineWidth', 1.5, 'DisplayName', sprintf('BIP%02d', ii), 'Visible', 'off' );
                app.(sprintf('BIP%02dSwitch',ii)).FontColor = cdata.BIP(ii,:);
                app.(sprintf('BIP%02dSwitchLabel', ii)).FontColor = cdata.BIP(ii,:);                
            end
            
            app.init_filters();
            
            app.SAGALabel.Text = sprintf("SAGA-%s", app.Tag);
            app.setDateTimeLabel(datetime('now'));
            app.enable_unipolar_stream(1:4);
            app.enable_bipolar_stream(1:4);
        end

        % Value changed function: ToggleAllUnipolarChannelsSwitch
        function ToggleAllUnipolarChannelsSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                 app.disable_unipolar_stream(1:64);
            else
                app.enable_unipolar_stream(1:64);
            end
        end

        % Value changed function: ToggleAllBipolarChannelsSwitch
        function ToggleAllBipolarChannelsSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                 app.disable_bipolar_stream(1:4);
            else
                app.enable_bipolar_stream(1:4);
            end
        end

        % Value changed function: UNI01Switch, UNI02Switch, 
        % UNI03Switch, UNI04Switch, UNI05Switch, UNI06Switch, 
        % UNI07Switch, UNI08Switch, UNI09Switch, UNI10Switch, 
        % UNI11Switch, UNI12Switch, UNI13Switch, UNI14Switch, 
        % UNI15Switch, UNI16Switch, UNI17Switch, UNI18Switch, 
        % UNI19Switch, UNI20Switch, UNI21Switch, UNI22Switch, 
        % UNI23Switch, UNI24Switch, UNI25Switch, UNI26Switch, 
        % UNI27Switch, UNI28Switch, UNI29Switch, UNI30Switch, 
        % UNI31Switch, UNI32Switch, UNI33Switch, UNI34Switch, 
        % UNI35Switch, UNI36Switch, UNI37Switch, UNI38Switch, 
        % UNI39Switch, UNI40Switch, UNI41Switch, UNI42Switch, 
        % UNI43Switch, UNI44Switch, UNI45Switch, UNI46Switch, 
        % UNI47Switch, UNI48Switch, UNI49Switch, UNI50Switch, 
        % UNI51Switch, UNI52Switch, UNI53Switch, UNI54Switch, 
        % UNI55Switch, UNI56Switch, UNI57Switch, UNI58Switch, 
        % UNI59Switch, UNI60Switch, UNI61Switch, UNI62Switch, 
        % UNI63Switch, UNI64Switch
        function UnipolarChannelSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                disable_unipolar_stream(app, str2double(event.Source.Tag));
            else
                enable_unipolar_stream(app, str2double(event.Source.Tag));
            end
        end

        % Value changed function: BIP01Switch, BIP02Switch, 
        % BIP03Switch, BIP04Switch
        function BipolarSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                disable_bipolar_stream(app, str2double(event.Source.Tag));
            else
                enable_bipolar_stream(app, str2double(event.Source.Tag));
            end
        end

        % Value changed function: BipolarCutoff1EditField, 
        % BipolarCutoff2EditField, BipolarFilterCheckBox, 
        % UnipolarCARCheckBox, UnipolarCutoff1EditField, 
        % UnipolarCutoff2EditField, UnipolarFilterCheckBox
        function FilterParameterValueChanged(app, event)
            app.init_filters();
        end

        % Value changed function: BipolarPerChannelOffsetEditField, 
        % UnipolarPerChannelOffsetEditField
        function PerChannelOffsetEditFieldValueChanged(app, event)
            prev = event.PreviousValue;
            value = event.Value;
            dy = value - prev;
            h = app.H.stream.(event.Source.Tag);
            for ii = 1:numel(h)
                new_offset = (ii - 1) * dy;
                h(ii).YData = h(ii).YData + new_offset;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.mode = selectedTab.Tag;
            if ismember(app.mode, {'US', 'UA'})
                ch = char(app.cfg.SAGA.Channels.UNI(app.cfg.SAGA.Channels.en.UNI) + 96);
                msg = char(sprintf('%s.%s', app.mode, ch));
            elseif ismember(app.mode, {'BS', 'BA'})
                ch = char(app.cfg.SAGA.Channels.BIP(app.cfg.SAGA.Channels.en.BIP) + 96);
                msg = char(sprintf('%s.%s', app.mode, ch));
            else
                msg = char(app.mode);    
            end
            app.udp.write([msg, 10], app.cfg.Server.Address.UDP, app.cfg.Server.UDP.extra);
            if app.connected
                app.tcp.write([msg, 10], "char");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAGADataViewerUIFigure = uifigure('Visible', 'off');
            app.SAGADataViewerUIFigure.Name = 'SAGA Data Viewer';
            app.SAGADataViewerUIFigure.Icon = 'outline_live_tv_black_24dp.png';
            app.TabGroup = uitabgroup(app.SAGADataViewerUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.UnipolarStreamTab = uitab(app.TabGroup);
            app.UnipolarStreamTab.Title = 'Unipolar Stream';
            app.UnipolarStreamTab.Tag = 'US';
            app.UnipolarStreamGridLayout = uigridlayout(app.UnipolarStreamTab);
            app.UnipolarUIAxes = uiaxes(app.UnipolarStreamGridLayout);
            title(app.UnipolarUIAxes, 'Unipolar Streams')
            xlabel(app.UnipolarUIAxes, 'Time (s)')
            ylabel(app.UnipolarUIAxes, 'Amplitude (\muV)')
            zlabel(app.UnipolarUIAxes, 'Z')
            app.UnipolarStreamChannelsPanel = uipanel(app.UnipolarStreamGridLayout);
            app.UnipolarStreamChannelsPanel.Title = 'Unipolar Stream Channels';
            app.UNI01SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI01SwitchLabel.Text = 'UNI01';
            app.UNI01Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI01Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI01Switch.Tag = '1';
            app.UNI02Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI02Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI02Switch.Tag = '2';
            app.UNI02SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI02SwitchLabel.Text = 'UNI02';
            app.UNI03Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI03Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI03Switch.Tag = '3';
            app.UNI03SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI03SwitchLabel.Text = 'UNI03';
            app.UNI04Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI04Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI04Switch.Tag = '4';
            app.UNI04SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI04SwitchLabel.Tag = '4';
            app.UNI04SwitchLabel.Text = 'UNI04';
            app.UNI05Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI05Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI05Switch.Tag = '5';
            app.UNI05SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI05SwitchLabel.Tag = '5';
            app.UNI05SwitchLabel.Text = 'UNI05';
            app.UNI06Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI06Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI06Switch.Tag = '6';
            app.UNI06SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI06SwitchLabel.Tag = '6';
            app.UNI06SwitchLabel.Text = 'UNI06';
            app.UNI07Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI07Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI07Switch.Tag = '7';
            app.UNI07SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI07SwitchLabel.Tag = '7';
            app.UNI07SwitchLabel.Text = 'UNI07';
            app.UNI08Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI08Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI08Switch.Tag = '8';
            app.UNI08SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI08SwitchLabel.Tag = '8';
            app.UNI08SwitchLabel.Text = 'UNI08';
            app.UNI09Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI09Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI09Switch.Tag = '9';
            app.UNI09SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI09SwitchLabel.Text = 'UNI09';
            app.UNI10Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI10Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI10Switch.Tag = '10';
            app.UNI10SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI10SwitchLabel.Text = 'UNI10';
            app.UNI11Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI11Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI11Switch.Tag = '11';
            app.UNI11SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI11SwitchLabel.Text = 'UNI11';
            app.UNI12Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI12Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI12Switch.Tag = '12';
            app.UNI12SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI12SwitchLabel.Tag = '4';
            app.UNI12SwitchLabel.Text = 'UNI12';
            app.UNI13Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI13Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI13Switch.Tag = '13';
            app.UNI13SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI13SwitchLabel.Tag = '5';
            app.UNI13SwitchLabel.Text = 'UNI13';
            app.UNI14Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI14Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI14Switch.Tag = '14';
            app.UNI14SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI14SwitchLabel.Tag = '6';
            app.UNI14SwitchLabel.Text = 'UNI14';
            app.UNI15Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI15Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI15Switch.Tag = '15';
            app.UNI15SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI15SwitchLabel.Tag = '7';
            app.UNI15SwitchLabel.Text = 'UNI15';
            app.UNI16Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI16Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI16Switch.Tag = '16';
            app.UNI16SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI16SwitchLabel.Tag = '8';
            app.UNI16SwitchLabel.Text = 'UNI16';
            app.UNI17Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI17Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI17Switch.Tag = '17';
            app.UNI17SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI17SwitchLabel.Text = 'UNI17';
            app.UNI18Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI18Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI18Switch.Tag = '18';
            app.UNI18SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI18SwitchLabel.Text = 'UNI18';
            app.UNI19Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI19Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI19Switch.Tag = '19';
            app.UNI19SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI19SwitchLabel.Text = 'UNI19';
            app.UNI20Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI20Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI20Switch.Tag = '20';
            app.UNI20SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI20SwitchLabel.Tag = '4';
            app.UNI20SwitchLabel.Text = 'UNI20';
            app.UNI21Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI21Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI21Switch.Tag = '21';
            app.UNI21SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI21SwitchLabel.Tag = '5';
            app.UNI21SwitchLabel.Text = 'UNI21';
            app.UNI22Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI22Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI22Switch.Tag = '22';
            app.UNI22SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI22SwitchLabel.Tag = '6';
            app.UNI22SwitchLabel.Text = 'UNI22';
            app.UNI23Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI23Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI23Switch.Tag = '23';
            app.UNI23SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI23SwitchLabel.Tag = '7';
            app.UNI23SwitchLabel.Text = 'UNI23';
            app.UNI24Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI24Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI24Switch.Tag = '24';
            app.UNI24SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI24SwitchLabel.Tag = '8';
            app.UNI24SwitchLabel.Text = 'UNI24';
            app.UNI25Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI25Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI25Switch.Tag = '25';
            app.UNI25SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI25SwitchLabel.Text = 'UNI25';
            app.UNI26Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI26Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI26Switch.Tag = '26';
            app.UNI26SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI26SwitchLabel.Text = 'UNI26';
            app.UNI27Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI27Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI27Switch.Tag = '27';
            app.UNI27SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI27SwitchLabel.Text = 'UNI27';
            app.UNI28Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI28Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI28Switch.Tag = '28';
            app.UNI28SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI28SwitchLabel.Tag = '4';
            app.UNI28SwitchLabel.Text = 'UNI28';
            app.UNI29Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI29Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI29Switch.Tag = '29';
            app.UNI29SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI29SwitchLabel.Tag = '5';
            app.UNI29SwitchLabel.Text = 'UNI29';
            app.UNI30Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI30Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI30Switch.Tag = '30';
            app.UNI30SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI30SwitchLabel.Tag = '6';
            app.UNI30SwitchLabel.Text = 'UNI30';
            app.UNI31Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI31Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI31Switch.Tag = '31';
            app.UNI31SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI31SwitchLabel.Tag = '7';
            app.UNI31SwitchLabel.Text = 'UNI31';
            app.UNI32Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI32Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI32Switch.Tag = '32';
            app.UNI32SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI32SwitchLabel.Tag = '8';
            app.UNI32SwitchLabel.Text = 'UNI32';
            app.UNI33Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI33Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI33Switch.Tag = '33';
            app.UNI33SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI33SwitchLabel.Text = 'UNI33';
            app.UNI34Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI34Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI34Switch.Tag = '34';
            app.UNI34SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI34SwitchLabel.Text = 'UNI34';
            app.UNI35Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI35Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI35Switch.Tag = '35';
            app.UNI35SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI35SwitchLabel.Text = 'UNI35';
            app.UNI36Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI36Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI36Switch.Tag = '36';
            app.UNI36SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI36SwitchLabel.Tag = '4';
            app.UNI36SwitchLabel.Text = 'UNI36';
            app.UNI37Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI37Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI37Switch.Tag = '37';
            app.UNI37SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI37SwitchLabel.Tag = '5';
            app.UNI37SwitchLabel.Text = 'UNI37';
            app.UNI38Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI38Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI38Switch.Tag = '38';
            app.UNI38SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI38SwitchLabel.Tag = '6';
            app.UNI38SwitchLabel.Text = 'UNI38';
            app.UNI39Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI39Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI39Switch.Tag = '39';
            app.UNI39SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI39SwitchLabel.Tag = '7';
            app.UNI39SwitchLabel.Text = 'UNI39';
            app.UNI40Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI40Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI40Switch.Tag = '40';
            app.UNI40SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI40SwitchLabel.Tag = '8';
            app.UNI40SwitchLabel.Text = 'UNI40';
            app.UNI41Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI41Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI41Switch.Tag = '41';
            app.UNI41SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI41SwitchLabel.Text = 'UNI41';
            app.UNI42Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI42Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI42Switch.Tag = '42';
            app.UNI42SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI42SwitchLabel.Text = 'UNI42';
            app.UNI43Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI43Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI43Switch.Tag = '43';
            app.UNI43SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI43SwitchLabel.Text = 'UNI43';
            app.UNI44Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI44Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI44Switch.Tag = '44';
            app.UNI44SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI44SwitchLabel.Tag = '4';
            app.UNI44SwitchLabel.Text = 'UNI44';
            app.UNI45Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI45Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI45Switch.Tag = '45';
            app.UNI45SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI45SwitchLabel.Tag = '5';
            app.UNI45SwitchLabel.Text = 'UNI45';
            app.UNI46Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI46Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI46Switch.Tag = '46';
            app.UNI46SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI46SwitchLabel.Tag = '6';
            app.UNI46SwitchLabel.Text = 'UNI46';
            app.UNI47Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI47Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI47Switch.Tag = '47';
            app.UNI47SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI47SwitchLabel.Tag = '7';
            app.UNI47SwitchLabel.Text = 'UNI47';
            app.UNI48Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI48Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI48Switch.Tag = '48';
            app.UNI48SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI48SwitchLabel.Tag = '8';
            app.UNI48SwitchLabel.Text = 'UNI48';
            app.UNI49Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI49Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI49Switch.Tag = '49';
            app.UNI49SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI49SwitchLabel.Text = 'UNI49';
            app.UNI50Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI50Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI50Switch.Tag = '50';
            app.UNI50SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI50SwitchLabel.Text = 'UNI50';
            app.UNI51Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI51Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI51Switch.Tag = '51';
            app.UNI51SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI51SwitchLabel.Text = 'UNI51';
            app.UNI52Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI52Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI52Switch.Tag = '52';
            app.UNI52SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI52SwitchLabel.Tag = '4';
            app.UNI52SwitchLabel.Text = 'UNI52';
            app.UNI53Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI53Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI53Switch.Tag = '53';
            app.UNI53SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI53SwitchLabel.Tag = '5';
            app.UNI53SwitchLabel.Text = 'UNI53';
            app.UNI54Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI54Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI54Switch.Tag = '54';
            app.UNI54SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI54SwitchLabel.Tag = '6';
            app.UNI54SwitchLabel.Text = 'UNI54';
            app.UNI55Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI55Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI55Switch.Tag = '55';
            app.UNI55SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI55SwitchLabel.Tag = '7';
            app.UNI55SwitchLabel.Text = 'UNI55';
            app.UNI56Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI56Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI56Switch.Tag = '56';
            app.UNI56SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI56SwitchLabel.Tag = '8';
            app.UNI56SwitchLabel.Text = 'UNI56';
            app.UNI57Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI57Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI57Switch.Tag = '57';
            app.UNI57SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI57SwitchLabel.Text = 'UNI57';
            app.UNI58Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI58Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI58Switch.Tag = '58';
            app.UNI58SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI58SwitchLabel.Text = 'UNI58';
            app.UNI59Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI59Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI59Switch.Tag = '59';
            app.UNI59SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI59SwitchLabel.Text = 'UNI59';
            app.UNI60Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI60Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI60Switch.Tag = '60';
            app.UNI60SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI60SwitchLabel.Tag = '4';
            app.UNI60SwitchLabel.Text = 'UNI60';
            app.UNI61Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI61Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI61Switch.Tag = '61';
            app.UNI61SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI61SwitchLabel.Tag = '5';
            app.UNI61SwitchLabel.Text = 'UNI61';
            app.UNI62Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI62Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI62Switch.Tag = '62';
            app.UNI62SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI62SwitchLabel.Tag = '6';
            app.UNI62SwitchLabel.Text = 'UNI62';
            app.UNI63Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI63Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI63Switch.Tag = '63';
            app.UNI63SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI63SwitchLabel.Tag = '7';
            app.UNI63SwitchLabel.Text = 'UNI63';
            app.UNI64Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI64Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI64Switch.Tag = '64';
            app.UNI64SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI64SwitchLabel.Tag = '8';
            app.UNI64SwitchLabel.Text = 'UNI64';
            app.UnipolarStreamSettingsPanel = uipanel(app.UnipolarStreamGridLayout);
            app.UnipolarStreamSettingsPanel.Title = 'Unipolar Stream Settings';
            app.AllChannelsSwitchLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.AllChannelsSwitchLabel.Text = 'All Channels';
            app.ToggleAllUnipolarChannelsSwitch = uiswitch(app.UnipolarStreamSettingsPanel, 'toggle');
            app.ToggleAllUnipolarChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAllUnipolarChannelsSwitchValueChanged, true);
            app.PerChannelOffsetVEditFieldLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.PerChannelOffsetVEditFieldLabel.Text = 'Per-Channel Offset (µV)';
            app.UnipolarPerChannelOffsetEditField = uieditfield(app.UnipolarStreamSettingsPanel, 'numeric');
            app.UnipolarPerChannelOffsetEditField.Limits = [0 5000];
            app.UnipolarPerChannelOffsetEditField.RoundFractionalValues = 'on';
            app.UnipolarPerChannelOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PerChannelOffsetEditFieldValueChanged, true);
            app.UnipolarPerChannelOffsetEditField.Tag = 'UNI';
            app.UnipolarPerChannelOffsetEditField.Value = 250;
            app.UnipolarFilterCheckBox = uicheckbox(app.UnipolarStreamSettingsPanel);
            app.UnipolarFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarFilterCheckBox.Text = 'Filter';
            app.UnipolarFilterCheckBox.Value = true;
            app.Cutoff1HzEditFieldLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.Cutoff1HzEditFieldLabel.Text = 'Cutoff 1 (Hz)';
            app.UnipolarCutoff1EditField = uieditfield(app.UnipolarStreamSettingsPanel, 'numeric');
            app.UnipolarCutoff1EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarCutoff1EditField.Tag = 'fc_low';
            app.UnipolarCutoff1EditField.Value = 10;
            app.Cutoff2HzEditFieldLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.Cutoff2HzEditFieldLabel.Text = 'Cutoff 2 (Hz)';
            app.UnipolarCutoff2EditField = uieditfield(app.UnipolarStreamSettingsPanel, 'numeric');
            app.UnipolarCutoff2EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarCutoff2EditField.Tag = 'fc_high';
            app.UnipolarCutoff2EditField.Value = 300;
            app.UnipolarCARCheckBox = uicheckbox(app.UnipolarStreamSettingsPanel);
            app.UnipolarCARCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarCARCheckBox.Text = 'CAR';
            app.UnipolarCARCheckBox.Value = true;
            app.BipolarStreamTab = uitab(app.TabGroup);
            app.BipolarStreamTab.Title = 'Bipolar Stream';
            app.BipolarStreamTab.Tag = 'BS';
            app.BipolarStreamGridLayout = uigridlayout(app.BipolarStreamTab);
            app.BipolarUIAxes = uiaxes(app.BipolarStreamGridLayout);
            title(app.BipolarUIAxes, 'Bipolar Streams')
            xlabel(app.BipolarUIAxes, 'Time (s)')
            ylabel(app.BipolarUIAxes, 'Amplitude (\muV)')
            zlabel(app.BipolarUIAxes, 'Z')
            app.BipolarStreamChannelsPanel = uipanel(app.BipolarStreamGridLayout);
            app.BipolarStreamChannelsPanel.Title = 'Bipolar Stream Channels';
            app.BIP01SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP01SwitchLabel.Text = 'BIP01';
            app.BIP01Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP01Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP01Switch.Tag = '1';
            app.BIP02Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP02Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP02Switch.Tag = '2';
            app.BIP02SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP02SwitchLabel.Text = 'BIP02';
            app.BIP03Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP03Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP03Switch.Tag = '3';
            app.BIP03SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP03SwitchLabel.Text = 'BIP03';
            app.BIP04Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP04Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP04Switch.Tag = '4';
            app.BIP04SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP04SwitchLabel.Tag = '4';
            app.BIP04SwitchLabel.Text = 'BIP04';
            app.BipolarStreamSettingsPanel = uipanel(app.BipolarStreamGridLayout);
            app.BipolarStreamSettingsPanel.Title = 'Bipolar Stream Settings';
            app.ToggleAllBipolarChannelsSwitchLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.ToggleAllBipolarChannelsSwitchLabel.Text = 'All Channels';
            app.ToggleAllBipolarChannelsSwitch = uiswitch(app.BipolarStreamSettingsPanel, 'toggle');
            app.ToggleAllBipolarChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAllBipolarChannelsSwitchValueChanged, true);
            app.BipolarFilterCheckBox = uicheckbox(app.BipolarStreamSettingsPanel);
            app.BipolarFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarFilterCheckBox.Text = 'Filter';
            app.BipolarFilterCheckBox.Value = true;
            app.BipolarPerChannelOffsetVEditFieldLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.BipolarPerChannelOffsetVEditFieldLabel.Text = 'Per-Channel Offset (µV)';
            app.BipolarPerChannelOffsetEditField = uieditfield(app.BipolarStreamSettingsPanel, 'numeric');
            app.BipolarPerChannelOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PerChannelOffsetEditFieldValueChanged, true);
            app.BipolarPerChannelOffsetEditField.Tag = 'BIP';
            app.BipolarPerChannelOffsetEditField.Value = 250;
            app.BipolarCutoff1EditFieldLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.BipolarCutoff1EditFieldLabel.Text = 'Cutoff 1 (Hz)';
            app.BipolarCutoff1EditField = uieditfield(app.BipolarStreamSettingsPanel, 'numeric');
            app.BipolarCutoff1EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarCutoff1EditField.Tag = 'fc_low';
            app.BipolarCutoff1EditField.Value = 10;
            app.BipolarCutoff2EditFieldLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.BipolarCutoff2EditFieldLabel.Text = 'Cutoff 2 (Hz)';
            app.BipolarCutoff2EditField = uieditfield(app.BipolarStreamSettingsPanel, 'numeric');
            app.BipolarCutoff2EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarCutoff2EditField.Tag = 'fc_high';
            app.BipolarCutoff2EditField.Value = 300;
            app.UnipolarAverageTab = uitab(app.TabGroup);
            app.UnipolarAverageTab.Title = 'Unipolar Average';
            app.UnipolarAverageTab.Tag = 'UA';
            app.UnipolarAverageGridLayout = uigridlayout(app.UnipolarAverageTab);
            app.UnipolarAverageSettingsPanel = uipanel(app.UnipolarAverageGridLayout);
            app.UnipolarAverageSettingsPanel.Title = 'Unipolar Average Settings';
            app.BipolarAverageTab = uitab(app.TabGroup);
            app.BipolarAverageTab.Title = 'Bipolar Average';
            app.BipolarAverageTab.Tag = 'BA';
            app.BipolarAverageGridLayout = uigridlayout(app.BipolarAverageTab);
            app.BipolarAverageSettingsPanel = uipanel(app.BipolarAverageGridLayout);
            app.BipolarAverageSettingsPanel.Title = 'Bipolar Average Settings';
            app.UnipolarRasterTab = uitab(app.TabGroup);
            app.UnipolarRasterTab.Title = 'Unipolar Raster';
            app.UnipolarRasterTab.Tag = 'UR';
            app.UnipolarRasterGridLayout = uigridlayout(app.UnipolarRasterTab);
            app.UnipolarRasterSettingsPanel = uipanel(app.UnipolarRasterGridLayout);
            app.UnipolarRasterSettingsPanel.Title = 'Unipolar Raster Settings';
            app.ICARasterTab = uitab(app.TabGroup);
            app.ICARasterTab.Title = 'ICA Raster';
            app.ICARasterTab.Tag = 'IR';
            app.ICARasterGridLayout = uigridlayout(app.ICARasterTab);
            app.ICARasterSettingsPanel = uipanel(app.ICARasterGridLayout);
            app.ICARasterSettingsPanel.Title = 'ICA Raster Settings';
            app.RMSContourTab = uitab(app.TabGroup);
            app.RMSContourTab.Title = 'RMS Contour';
            app.RMSContourTab.Tag = 'RC';
            app.RMSContourGridLayout = uigridlayout(app.RMSContourTab);
            app.RMSContourSettingsPanel = uipanel(app.RMSContourGridLayout);
            app.RMSContourSettingsPanel.Title = 'RMS Contour Settings';
            app.ParametersConnectionStatusLampLabel = uilabel(app.SAGADataViewerUIFigure);
            app.ParametersConnectionStatusLampLabel.Text = 'Parameters Connection Status';
            app.ParametersConnectionStatusLamp = uilamp(app.SAGADataViewerUIFigure);
            app.SAGALabel = uilabel(app.SAGADataViewerUIFigure);
            app.SAGALabel.Text = 'SAGA';
            app.DateTimeLabel = uilabel(app.SAGADataViewerUIFigure);
            % Show the figure after all components are created
            app.SAGADataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAGA_Data_Visualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAGADataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAGADataViewerUIFigure)
        end
    end
end